classdef BVGUI_current < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BootvalidationGUIPanel    matlab.ui.container.Panel
        ContractionDropDown       matlab.ui.control.DropDown
        ContractionDropDownLabel  matlab.ui.control.Label
        LoadfileButton            matlab.ui.control.Button
        PauseResumestimButton     matlab.ui.control.StateButton
        SLRTPanel                 matlab.ui.container.Panel
        PassiveTQSpinner          matlab.ui.control.Spinner
        PassiveTQSpinnerLabel     matlab.ui.control.Label
        InitializetargetButton    matlab.ui.control.Button
        StartStopButton           slrealtime.ui.control.StartStopButton
        ConnectButton             slrealtime.ui.control.ConnectButton
        SelectstimDropDown        matlab.ui.control.DropDown
        SelectstimDropDownLabel   matlab.ui.control.Label
        StartstimButton           matlab.ui.control.Button
        LoadstimButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadstimButton
        function LoadstimButtonPushed(app, event)
            
            global tg

            fullFileName = getappdata(0,'address');


            inputfile = load(fullFileName);
            inputfile = inputfile.inputs;


           value_cont =  getappdata(0,'value_cont');


             if value_cont == 'PF'
                stimulus = inputfile.STAIR_ASCEND;

            setparam(tg, 'Supine_SpeedGoat_v1_original/Visual Feedback Controller/Contraction', 'Value', 0)
            disp("Plantarflextion mode")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Visual Feedback Controller/Contraction', 'Value')


            elseif value_cont == 'DF'


            setparam(tg, 'Supine_SpeedGoat_v1_original/Visual Feedback Controller/Contraction', 'Value', 0)
            disp("Dorsiflextion mode")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Visual Feedback Controller/Contraction', 'Value')

             end


           value =  getappdata(0,'value');


             if value == 'SA'
                stimulus = inputfile.SA;


                % New
                gainval = 100;

                %old
%                 gainval = 80;


            setparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain', gainval)
            disp("Proportional Gain has been set at:")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain')


            elseif value == 'n6'

                stimulus = inputfile.n6;
                gainval = 75;
%                 gainval = 88;


            setparam(tg, 'Supine_SpeedGoat_v1_original/TRIAL_SWITCH', 'Value', 2)
            setparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain', gainval)
            disp("Proportional Gain has been set at:")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain')

            elseif value == 'n4'
                stimulus = inputfile.n4;
                gainval = 75;
%                 gainval = 91;


            setparam(tg, 'Supine_SpeedGoat_v1_original/TRIAL_SWITCH', 'Value', 3)
            setparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain', gainval)
            disp("Proportional Gain has been set at:")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain')

            elseif value == 'n3'
                stimulus = inputfile.n3;


            gainval = 75;
%             gainval = 88;

            setparam(tg, 'Supine_SpeedGoat_v1_original/TRIAL_SWITCH', 'Value', 4)
            setparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain', gainval)
            disp("Proportional Gain has been set at:")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain')

            elseif value == 'n1'
                stimulus = inputfile.n1;

            gainval = 75;
%             gainval = 72.5;


            setparam(tg, 'Supine_SpeedGoat_v1_original/TRIAL_SWITCH', 'Value', 5)
            setparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain', gainval)
            disp("Proportional Gain has been set at:")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain')

            elseif value == 'n0'
                stimulus = inputfile.n0;

            gainval= 75;
%             gainval= 85.5;


            setparam(tg, 'Supine_SpeedGoat_v1_original/TRIAL_SWITCH', 'Value', 6)
            setparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain', gainval)
            disp("Proportional Gain has been set at:")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain')

            elseif value == 'p1'
                stimulus = inputfile.p1;

            gainval = 75;
%             gainval = 79;


            setparam(tg, 'Supine_SpeedGoat_v1_original/TRIAL_SWITCH', 'Value', 7)
            setparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain', gainval)
            disp("Proportional Gain has been set at:")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain')

            elseif value == 'p3'
                stimulus = inputfile.p3;


            gainval = 75;
%             gainval = 79;

            setparam(tg, 'Supine_SpeedGoat_v1_original/TRIAL_SWITCH', 'Value', 8)
            setparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain', gainval)
            disp("Proportional Gain has been set at:")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain')


            elseif value == 'p4'
                stimulus = inputfile.p4;


            gainval = 75;
%             gainval = 79;

            setparam(tg, 'Supine_SpeedGoat_v1_original/TRIAL_SWITCH', 'Value', 9)
            setparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain', gainval)
            disp("Proportional Gain has been set at:")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Servo Controller/P', 'Gain')


                        end

           disp(value)
           disp("Has been loaded")
                        
            setappdata(0,'stimulus',stimulus);


        end

        % Drop down opening function: SelectstimDropDown
        function SelectstimDropDownOpening(app, event)
            
        end

        % Value changed function: SelectstimDropDown
        function SelectstimDropDownValueChanged(app, event)
           
            value = app.SelectstimDropDown.Value;
            setappdata(0,'value',value);
            eval('value')


        end

        % Button pushed function: StartstimButton
        function StartstimButtonPushed(app, event)
            
            global tg;

            tg = slrealtime('TargetPC1');

            stimulus = getappdata(0,'stimulus');

            u = timeseries(stimulus(:,2),stimulus(:,1));

            stop(tg.Stimulation,[1]);
            reloadData(tg.Stimulation,[1],u);
            start(tg.Stimulation,[1]);
            
%             getStatus(tg.Stimulation,'all')


        end

        % Button pushed function: InitializetargetButton
        function InitializetargetButtonPushed(app, event)
            

        tg = slrealtime('TargetPC1');

        global tg

        newDir = 'G:\Users\Ali_R\Final_Delivery\slrt_models\';
        cd(newDir);

        sampleTime = 0.001;
        endTime = 5;
        numberOfSamples = endTime * 1/sampleTime + 1;
        timeVector = (0:numberOfSamples) * sampleTime;
        c = timeseries(timeVector*0,timeVector);
        model = 'Supine_SpeedGoat_v1_original';
        open_system(model);
        waveform = c;
        set_param(model,'ExternalInput','waveform');
        set_param(model,'LoadExternalInput','on');
        set_param(model,'StopTime','Inf');
        evalc('slbuild(model)');


%         load(tg,model);
%         start(tg.Stimulation,[1]);
% 
%         start(tg)
   

        end

        % Value changed function: PauseResumestimButton
        function PauseResumestimButtonValueChanged(app, event)
            value = app.PauseResumestimButton.Value;

            global tg

            if value
                pause(tg.Stimulation,[1]);
                getStatus(tg.Stimulation,'all')

            else
                start(tg.Stimulation,[1]);
                getStatus(tg.Stimulation,'all')

            end
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            

             [baseFileName, folder] = uigetfile('*.mat');
             fullFileName = fullfile(folder, baseFileName);

             setappdata(0,'address',fullFileName);
             disp("Selected stimulus file is : ")
             disp(fullFileName)


        end

        % Value changed function: PassiveTQSpinner
        function PassiveTQSpinnerValueChanged(app, event)
            value = app.PassiveTQSpinner.Value;

            global tg
            setparam(tg, 'Supine_SpeedGoat_v1_original/Visual Feedback Controller/Subsystem2/PTQ', 'Gain', value)
            disp("Passive Torque has been set at:")
            getparam(tg, 'Supine_SpeedGoat_v1_original/Visual Feedback Controller/Subsystem2/PTQ', 'Gain')


        end

        % Value changed function: ContractionDropDown
        function ContractionDropDownValueChanged(app, event)

            value_cont = app.ContractionDropDown.Value;

            setappdata(0,'value_cont',value_cont);
            eval('value_cont')
            
        end

        % Drop down opening function: ContractionDropDown
        function ContractionDropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BootvalidationGUIPanel = uipanel(app.UIFigure);
            app.BootvalidationGUIPanel.Title = 'Boot validation GUI';
            app.LoadstimButton = uibutton(app.BootvalidationGUIPanel, 'push');
            app.LoadstimButton.ButtonPushedFcn = createCallbackFcn(app, @LoadstimButtonPushed, true);
            app.LoadstimButton.Text = 'Load stim ';
            app.StartstimButton = uibutton(app.BootvalidationGUIPanel, 'push');
            app.StartstimButton.ButtonPushedFcn = createCallbackFcn(app, @StartstimButtonPushed, true);
            app.StartstimButton.Text = 'Start stim';
            app.SelectstimDropDownLabel = uilabel(app.BootvalidationGUIPanel);
            app.SelectstimDropDownLabel.Text = 'Select stim';
            app.SelectstimDropDown = uidropdown(app.BootvalidationGUIPanel);
            app.SelectstimDropDown.Items = {'n6', 'n4', 'n3', 'n1', 'n0', 'p1', 'p3', 'p4'};
            app.SelectstimDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectstimDropDownOpening, true);
            app.SelectstimDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectstimDropDownValueChanged, true);
            app.SelectstimDropDown.Value = 'n6';
            app.SLRTPanel = uipanel(app.BootvalidationGUIPanel);
            app.SLRTPanel.Title = 'SLRT';
            app.ConnectButton = slrealtime.ui.control.ConnectButton(app.SLRTPanel);
            app.StartStopButton = slrealtime.ui.control.StartStopButton(app.SLRTPanel);
            app.InitializetargetButton = uibutton(app.SLRTPanel, 'push');
            app.InitializetargetButton.ButtonPushedFcn = createCallbackFcn(app, @InitializetargetButtonPushed, true);
            app.InitializetargetButton.Text = 'Initialize target';
            app.PassiveTQSpinnerLabel = uilabel(app.SLRTPanel);
            app.PassiveTQSpinnerLabel.Text = 'Passive TQ';
            app.PassiveTQSpinner = uispinner(app.SLRTPanel);
            app.PassiveTQSpinner.Step = 0.01;
            app.PassiveTQSpinner.Limits = [-100 100];
            app.PassiveTQSpinner.ValueChangedFcn = createCallbackFcn(app, @PassiveTQSpinnerValueChanged, true);
            app.PauseResumestimButton = uibutton(app.BootvalidationGUIPanel, 'state');
            app.PauseResumestimButton.ValueChangedFcn = createCallbackFcn(app, @PauseResumestimButtonValueChanged, true);
            app.PauseResumestimButton.Text = 'Pause/Resume stim';
            app.LoadfileButton = uibutton(app.BootvalidationGUIPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.ContractionDropDownLabel = uilabel(app.BootvalidationGUIPanel);
            app.ContractionDropDownLabel.Text = 'Contraction';
            app.ContractionDropDown = uidropdown(app.BootvalidationGUIPanel);
            app.ContractionDropDown.Items = {'PF', 'DF'};
            app.ContractionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ContractionDropDownOpening, true);
            app.ContractionDropDown.ValueChangedFcn = createCallbackFcn(app, @ContractionDropDownValueChanged, true);
            app.ContractionDropDown.Value = 'PF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BVGUI_current

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end